classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        OpenButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [file,path] = uigetfile('*.obj');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
            end
            [V,F] = read_vertices_and_faces_from_obj_file(file);
            trisurf(F,V(:,1),V(:,2),V(:,3),'FaceColor',[0.26,0.33,1.0 ]);
            light('Position',[-1.0,-1.0,100.0],'Style','infinite');
            lighting phong;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end